classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartButton             matlab.ui.control.StateButton
        SavePathEditField       matlab.ui.control.EditField
        SavePathEditFieldLabel  matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        LoadFileEditField       matlab.ui.control.EditField
        LoadFileLabel           matlab.ui.control.Label
        FileButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: FileButton, SavePathEditField
        function FileButtonPushed(app, event)
            [f, p] = uigetfile({'*.mat','预测数据';'*.*','所有文件'},'请选择 .mat 文件');
            if isequal(f, 0); return; end

            try
                fullpath = fullfile(p, f);
                pred = load(fullpath, 'pred');                 % 返回一个struct

                app.pred = pred;                    % 存到属性
                app.StatusLabel.Text = sprintf('已加载 %d×%d', height(T), width(T));

                app.LoadFileEditField.Value = fullfile(p, f);
            catch ME
                uialert(app.UIFigure, ME.message, '读取失败');
                app.StatusLabel.Text = '读取失败';
            end
            
        end

        % Value changed function: LoadFileEditField
        function LoadFileEditFieldValueChanged(app, event)
            % value = app.LoadFileEditField.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % 选择一个文件夹路径（不选文件，而是目录）
            folder = uigetdir('', '请选择用于保存的文件夹');
            if isequal(folder, 0)
                return;  % 用户取消选择
            end
        
            % 保存路径到 app 属性中
            app.SaveFolder = folder;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = '...';
            app.LoadFileLabel = uilabel(app.UIFigure);
            app.LoadFileLabel.Text = 'Load File:';
            app.LoadFileEditField = uieditfield(app.UIFigure, 'text');
            app.LoadFileEditField.ValueChangedFcn = createCallbackFcn(app, @LoadFileEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '...';
            app.SavePathEditFieldLabel = uilabel(app.UIFigure);
            app.SavePathEditFieldLabel.Text = 'Save Path:';
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end